classdef FB_exploration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MetadataPanel           matlab.ui.container.Panel
        SessionDropDown         matlab.ui.control.DropDown
        SessionDropDownLabel    matlab.ui.control.Label
        RegionEditField         matlab.ui.control.EditField
        RegionEditFieldLabel    matlab.ui.control.Label
        AnimalIDEditField       matlab.ui.control.EditField
        AnimalIDEditFieldLabel  matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        PlotsPanel              matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        rawDataTab              matlab.ui.container.Tab
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
        PSTHTab                 matlab.ui.container.Tab
    end

    
    properties (Access = private)
        file % file to analyze
        path % directory of the file
        data % data to analzye
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % load data
            [app.file,app.path] = uigetfile('H:\Data\FB_data\Dopamine\*.mat');
            app.data = load([app.path, app.file]);
            idx = 1;
            plot(app.UIAxes, app.data.summarydata(idx).time, app.data.summarydata(idx).signal)
            p1 = plot(app.UIAxes, app.data.summarydata(idx).time, app.data.summarydata(idx).raw_signal)
            hold(app.UIAxes,'on');
            p2 = plot(app.UIAxes, app.data.summarydata(idx).time, app.data.summarydata(idx).raw_reference)

            legend(app.UIAxes, {'Signal', 'Reference'})
            plot(app.UIAxes2, app.data.summarydata(idx).time, app.data.summarydata(idx).signal)

            app.AnimalIDEditField.Value = app.data.summarydata(1).animalID;
        end

        % Value changed function: AnimalIDEditField
        function AnimalIDEditFieldValueChanged(app, event)


        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            value = app.SessionDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.TabGroup = uitabgroup(app.PlotsPanel);
            app.rawDataTab = uitab(app.TabGroup);
            app.rawDataTab.Title = 'rawData';
            app.UIAxes = uiaxes(app.rawDataTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (mv)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.rawDataTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Baseline corrected Z-score')
            zlabel(app.UIAxes2, 'Z')
            app.PSTHTab = uitab(app.TabGroup);
            app.PSTHTab.Title = 'PSTH';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.MetadataPanel = uipanel(app.UIFigure);
            app.MetadataPanel.Title = 'Metadata';
            app.AnimalIDEditFieldLabel = uilabel(app.MetadataPanel);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.MetadataPanel, 'text');
            app.AnimalIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnimalIDEditFieldValueChanged, true);
            app.RegionEditFieldLabel = uilabel(app.MetadataPanel);
            app.RegionEditFieldLabel.Text = 'Region';
            app.RegionEditField = uieditfield(app.MetadataPanel, 'text');
            app.SessionDropDownLabel = uilabel(app.MetadataPanel);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.MetadataPanel);
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.SessionDropDown.Value = 'Option 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FB_exploration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end